classdef proyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Button        matlab.ui.control.Button
        ListBox       matlab.ui.control.ListBox
        ListBoxLabel  matlab.ui.control.Label
        UIAxes_2      matlab.ui.control.UIAxes
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        countryVaccin
        casos1 % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Llamo a las bases de datos
            load("countryVaccin.mat","country_vaccinations");
            app.countryVaccin=country_vaccinations;
            load ("Casos1.mat","Casos1");
            app.casos1=Casos1;

        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            value = app.ListBox.Value;
            
            cla(app.UIAxes,"reset");
            cla(app.UIAxes_2, 'reset');
            if value==1
             
                %Contadores UIAxes 1
                bog_Recuperados=0;
                bog_norec=0;
                med_Recuperados=0;
                med_norec=0;
                cali_Recuperados=0;
                cali_norec=0;
                bar_Recuperados=0;
                bar_norec=0;

                %Contadores UIAxes 2
                cont_impor_9_30 = 0;
                cont_relac_9_30 = 0;
                 cont_impor_31_60 = 0;
                 cont_relac_31_60 = 0;
                 cont_impor_61_90 = 0;
                 cont_relac_61_90 = 0;
                 

                for i=1:length(app.casos1.CiudadDeUbicaci_n)
                    if contains(string(app.casos1.CiudadDeUbicaci_n(i)),"Bogot")
                        if app.casos1.Atenci_n__(i)=="Recuperado"
                            bog_Recuperados=bog_Recuperados+1;
                        else 
                            bog_norec=bog_norec+1;
                        end
                    
                elseif contains(string(app.casos1.CiudadDeUbicaci_n(i)),"Medell")
                    if app.casos1.Atenci_n__(i)=="Recuperado"
                            med_Recuperados=med_Recuperados+1;
                        else 
                            med_norec=med_norec+1;
                    end

               elseif app.casos1.CiudadDeUbicaci_n(i)=="Cali"
                    if app.casos1.Atenci_n__(i)=="Recuperado"
                            cali_Recuperados=cali_Recuperados+1;
                        else 
                            cali_norec=cali_norec+1;
                    end
              elseif app.casos1.CiudadDeUbicaci_n(i)=="Barranquilla"
                    if app.casos1.Atenci_n__(i)=="Recuperado"
                            bar_Recuperados=bar_Recuperados+1;
                        else 
                            bar_norec=bar_norec+1;
                    end

                    end

                end

x=["Bogotá"; "Medellín";"Cali";"Barranquilla"];
y=[bog_Recuperados bog_norec;med_Recuperados med_norec;cali_Recuperados cali_norec;bar_Recuperados bar_norec];
bar(app.UIAxes, x, y);
app.UIAxes.Title.String = 'Casos Covid Colombia';
        app.UIAxes.XLabel.String = 'Ciudad';
        app.UIAxes.YLabel.String = 'Cantidad de personas enferemas y recuperadas';

        for a=1:length(app.casos1.Edad)
            if app.casos1.Edad(a)>=9 && app.casos1.Edad(a)<=30
                if app.casos1.Tipo_(a)=="Importado"
                    cont_impor_9_30=cont_impor_9_30+1;
                else
                    cont_relac_9_30=cont_relac_9_30+1;
                end
            elseif app.casos1.Edad(a)>=31 && app.casos1.Edad(a)<=60
                if app.casos1.Tipo_(a)=="Importado"
                    cont_impor_31_60=cont_impor_31_60+1;
                else
                    cont_relac_31_60=cont_relac_31_60+1;
                end
                elseif app.casos1.Edad(a)>=61 && app.casos1.Edad(a)<=90
                if app.casos1.Tipo_(a)=="Importado"
                    cont_impor_61_90=cont_impor_61_90+1;
                else
                    cont_relac_61_90=cont_relac_61_90+1;
                end

            end

        end
x1 = ["9-30";"31-60";"61-90"];
y2=[cont_impor_9_30 cont_relac_9_30;cont_impor_31_60 cont_relac_31_60;cont_impor_61_90 cont_relac_61_90];
bar(app.UIAxes_2, x1, y2);
app.UIAxes_2.Title.String = 'Casos Covid Colombia';
        app.UIAxes_2.XLabel.String = 'Rango de edades';
        app.UIAxes_2.YLabel.String = 'Personas relacionadas e importadas';
     elseif value == 2
         afgh_vac=0;
         Canada_vac=0;
         Colombia_vac=0;
         Japan_vac=0;

         for i=1:length(app.countryVaccin.people_vaccinated)
             if string(app.countryVaccin.country(i))=="Afghanistan"
                 peopleVaccin=0;
                 com=isnan(app.countryVaccin.people_vaccinated(i));
                 if com==0 
                     peopleVaccin=app.countryVaccin.people_vaccinated(i);
                     
                 end
                 afgh_vac=afgh_vac+peopleVaccin;

             end
             if string(app.countryVaccin.country(i))=="Canada"
                 peopleVaccin=0;
                 com=isnan(app.countryVaccin.people_vaccinated(i));
                 if com==0 
                     peopleVaccin=app.countryVaccin.people_vaccinated(i);
                     
                 end
                 Canada_vac=Canada_vac+peopleVaccin;

             end
             if string(app.countryVaccin.country(i))=="Colombia"
                 peopleVaccin=0;
                 com=isnan(app.countryVaccin.people_vaccinated(i));
                 if com==0 
                     peopleVaccin=app.countryVaccin.people_vaccinated(i);
                     
                 end
                 Colombia_vac=Colombia_vac+peopleVaccin;

             end
             if string(app.countryVaccin.country(i))=="Japan"
                 peopleVaccin=0;
                 com=isnan(app.countryVaccin.people_vaccinated(i));
                 if com==0 
                     peopleVaccin=app.countryVaccin.people_vaccinated(i);
                     
                 end
                 Japan_vac=Japan_vac+peopleVaccin;

             end


         end
         
         x2=["Afghanistan";"Colombia";"Canada";"Japan"];
         y2=[afgh_vac;Colombia_vac;Canada_vac;Japan_vac];
         bar(app.UIAxes,x2,y2);

afgh_vac
Canada_vac
Colombia_vac
Japan_vac
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Casos 1', 'Country'};
            app.ListBox.ItemsData = [1 2];
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 1;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyecto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end